classdef BeamFitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageAxes                    matlab.ui.control.UIAxes
        VerticalCutAxes              matlab.ui.control.UIAxes
        PredefinedImagesLabel        matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        LoadButton                   matlab.ui.control.Button
        HorizontalCutAxes            matlab.ui.control.UIAxes
        FitButton                    matlab.ui.control.Button
        ResizeFactorEditFieldLabel   matlab.ui.control.Label
        ResizeFactorEditField        matlab.ui.control.NumericEditField
        PixelConvEditFieldLabel      matlab.ui.control.Label
        PixelConvEditField           matlab.ui.control.NumericEditField
        FixedRotationCheckBox        matlab.ui.control.CheckBox
        xminpxEditFieldLabel         matlab.ui.control.Label
        xminpxEditField              matlab.ui.control.NumericEditField
        widthpxEditFieldLabel        matlab.ui.control.Label
        widthpxEditField             matlab.ui.control.NumericEditField
        heightpxEditFieldLabel       matlab.ui.control.Label
        heightpxEditField            matlab.ui.control.NumericEditField
        yminpxEditFieldLabel         matlab.ui.control.Label
        yminpxEditField              matlab.ui.control.NumericEditField
        RotationangleEditFieldLabel  matlab.ui.control.Label
        RotationangleEditField       matlab.ui.control.NumericEditField
        XmmEditFieldLabel            matlab.ui.control.Label
        XmmEditField                 matlab.ui.control.NumericEditField
        YmmEditFieldLabel            matlab.ui.control.Label
        YmmEditField                 matlab.ui.control.NumericEditField
        waistxmmEditFieldLabel       matlab.ui.control.Label
        waistxmmEditField            matlab.ui.control.NumericEditField
        waistymmEditFieldLabel       matlab.ui.control.Label
        waistymmEditField            matlab.ui.control.NumericEditField
        RotationEditFieldLabel       matlab.ui.control.Label
        RotationEditField            matlab.ui.control.NumericEditField
        ExpmsEditFieldLabel          matlab.ui.control.Label
        ExpmsEditField               matlab.ui.control.NumericEditField
        CaptureButton                matlab.ui.control.Button
        PixelsizemLabel              matlab.ui.control.Label
        PixelsizemEditField          matlab.ui.control.NumericEditField
        LiveButton                   matlab.ui.control.StateButton
        PeriodsEditFieldLabel        matlab.ui.control.Label
        PeriodsEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        webcamObject;
        DCxcamObject;
        MemoryTimer;
        uc480path='C:\Program Files\Thorlabs\Scientific Imaging\DCx Camera Support\Develop\DotNet\uc480DotNet.dll';
            
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'after pinhole.bmp')
                im = imread('after pinhole.bmp');
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            imagesc(app.ImageAxes,im(:,:,1));
            app.ImageAxes.XTick(2) = size(im,2);
            app.ImageAxes.YTick(2) = size(im,1);
            createBox(app); 

        end

        function openDCxThorcam(app)
            if isfile(app.uc480path) %check if library installed
                try
                    NET.addAssembly(app.uc480path); 
                    camera_no=0;
                    app.DCxcamObject  = uc480.Camera;
                    app.DCxcamObject.Init(camera_no); 
                    % Set display mode to bitmap (DiB) 
                    app.DCxcamObject.Display.Mode.Set(uc480.Defines.DisplayMode.Mono); 
                    % Set color mode to 8-bit RGB 
                    app.DCxcamObject.PixelFormat.Set(uc480.Defines.ColorMode.Mono8);
                    % Set trigger mode to software (single image acquisition) 
                    app.DCxcamObject.Trigger.Set(uc480.Defines.TriggerMode.Software); 
                    captureImage(app)
                catch ME
                    uialert(app.UIFigure, ME.message, 'Camera Error');
                
                end
            else
                uialert(app.UIFigure, 'Driver for DCx camerea not found. Please check path or install from Thorlabs website. URL: https://www.thorlabs.com/software_pages/ViewSoftwarePage.cfm?Code=ThorCam' , 'Camera Error');
                [tmpfile, tmppath,~ ] = uigetfile('*.dll','test',app.uc480path);
                if tmpfile
                    app.uc480path = [tmppath tmpfile];
                    openDCxThorcam(app)
                end
            end
        
        end
        function captureImage(app)
            cam=app.DCxcamObject;
            exp_time_ms=app.ExpmsEditField.Value;
            cam.Timing.Exposure.Set(exp_time_ms);
            [~, MemId] = cam.Memory.Allocate(true); 
              
            %Acquire single image
            cam.Acquisition.Freeze(uc480.Defines.DeviceParameter.Wait); 
            % Copy image from memory 
            [~, tmp] = cam.Memory.CopyToArray(MemId); 
            % Obtain image information 
            [~, Width, Height, Bits, ~] = cam.Memory.Inquire(MemId); 
            % Reshape image 
            Data = reshape(uint8(tmp), [Bits/8, Width, Height]); 
            Data = Data(1, 1:Width, 1:Height); 
            
            Data = permute(Data, [3,2,1]);
            
            %im = image(app.ImageAxes,zeros(size(Data),'uint8')); 
            axis(app.ImageAxes,'image');
            updateDCxThorcam(app,Data);
                    
        end
        function updateDCxThorcam(app,im)
            imagesc(app.ImageAxes,im(:,:,1));
            app.ImageAxes.XTick(2) = size(im,2);
            app.ImageAxes.YTick(2) = size(im,1);
            createBox(app); 
        end
        function closeDCxThorcam(app)
            try app.DCxcamObject.IsOpened
                app.DCxcamObject.Exit
            catch ME
                
            end
        end
        
        function updatecuts(app,data_fit,horizontal_cut,vertical_cut,P)
            % Create histograms based on number of color channels
            % Plot the histograms
            horiz_x=(1:size(horizontal_cut,1))';
            vert_x=(1:size(vertical_cut,1))';
            axh = plot(app.HorizontalCutAxes, horiz_x,horizontal_cut);
            axv = plot(app.VerticalCutAxes, vert_x,vertical_cut);
            pix_size=app.PixelsizemEditField.Value*1e-3; % um to mm
            resize_fac=app.ResizeFactorEditField.Value;
            app.XmmEditField.Value = P(3)*resize_fac*pix_size;
            app.YmmEditField.Value = P(4)*resize_fac*pix_size;
            app.waistxmmEditField.Value = P(5)*resize_fac*pix_size;
            app.waistymmEditField.Value = P(6)*resize_fac*pix_size;   
            app.RotationEditField.Value = P(7);
%                 % Set y axes limits based on largest bin count
%                 app.HorizontalCutAxes.YLim = [0 maxcount];
%                 app.HorizontalCutAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
%                 app.VerticalCutAxes.YLim = [0 maxcount];
%                 app.VerticalCutAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
%                 app.BlueAxes.YLim = [0 maxcount];
%                 app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
%          
        end
        
        function createBox(app)
            xmin = app.xminpxEditField.Value;
            ymin = app.yminpxEditField.Value;
            width = app.widthpxEditField.Value;
            height = app.heightpxEditField.Value;
            r = findobj(app.ImageAxes,'Type','Rectangle');
            if isempty(r)
                rectangle(app.ImageAxes,'Position',[xmin ymin width height],'EdgeColor','g');
            else
                r.Position=[xmin ymin width height];
            end
        end
        
        function MemoryTimerFcn(app,~,~)
           captureImage(app)
       end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'Gaussian beam.bmp');
            createBox(app)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            if strcmp(app.DropDown.Value,'WEBcam')
                closeDCxThorcam(app);
                app.ExpmsEditField.Visible = false;
                app.ExpmsEditFieldLabel.Visible = false;
                app.CaptureButton.Visible = false;
                app.LiveButton.Visible = false;
                app.PeriodsEditField.Visible = false;
                app.PeriodsEditFieldLabel.Visible = false;
                delete(app.MemoryTimer)
                
                
                if exist('webcamlist','file') == 2
                    try
                        if ~isempty(webcamlist)
                            app.webcamObject = webcam;
                            
                            frame = snapshot(app.webcamObject); 
                            im = image(app.ImageAxes,zeros(size(frame),'uint8')); 
                            axis(app.ImageAxes,'image');
                            preview(app.webcamObject,im);
                            app.ImageAxes.XTick(2) = size(im,2);
                            app.ImageAxes.YTick(2) = size(im,1);
                        end
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Camera Error');
                        
                    end
                else
                     uialert(app.UIFigure, 'Matlab support for webcam not installed', 'Camera Error');
                end
            elseif strcmp(app.DropDown.Value,'Thorlabs DCx cam')
                app.ExpmsEditField.Visible = true;
                app.ExpmsEditFieldLabel.Visible = true;
                app.CaptureButton.Visible = true;
                app.LiveButton.Visible = true;
                app.PeriodsEditField.Visible = true;
                app.PeriodsEditFieldLabel.Visible = true;
                
                openDCxThorcam(app);
                app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.PeriodsEditField.Value, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            else
                closeDCxThorcam(app);
                app.ExpmsEditField.Visible = false;
                app.ExpmsEditFieldLabel.Visible = false;
                app.CaptureButton.Visible = false;
                app.LiveButton.Visible = false;
                app.PeriodsEditField.Visible = false;
                app.PeriodsEditFieldLabel.Visible = false;
                delete(app.MemoryTimer)
                
                updateimage(app, app.DropDown.Value);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Callback function: FitButton, waistymmEditField
        function fit(app, event)
            if strcmp(app.DropDown.Value,'WEBcam')
                snapshot(app.webcamObject);
                
                im_hand= findobj(app.ImageAxes,'Type','Image');
                im=im_hand.CData;
                im=rgb2gray(im);
                im_hand.CData=im;
            elseif strcmp(app.DropDown.Value,'Thorlabs DCx cam')
                stop(app.MemoryTimer)
                app.LiveButton.Value = 0;
                im_hand= findobj(app.ImageAxes,'Type','Image');
                im=im_hand.CData;
            else
                app.LiveButton.Value = 0;
                im_hand= findobj(app.ImageAxes,'Type','Image');
                im=im_hand.CData;
            end
            im_cp=imcrop(im,[app.xminpxEditField.Value app.yminpxEditField.Value app.widthpxEditField.Value app.heightpxEditField.Value]);
            resize_factor=app.ResizeFactorEditField.Value;
            Pixel_conv=app.PixelConvEditField.Value;
            rot_angle=app.RotationangleEditField.Value;
            rot_true=app.FixedRotationCheckBox.Value;
            [P ,data_fit,horizontal_cut,vertical_cut]=fit_waist(im_cp,resize_factor,Pixel_conv,rot_angle,rot_true);
            updatecuts(app,data_fit,horizontal_cut,vertical_cut,P);
        end

        % Value changed function: FixedRotationCheckBox
        function FixedRotationCheckBoxValueChanged(app, event)
            value = app.FixedRotationCheckBox.Value;
            if value == 1
                app.RotationEditField.Visible = false;
                app.RotationEditFieldLabel.Visible = false;
            elseif value == 0
                app.RotationEditField.Visible = true;
                app.RotationEditFieldLabel.Visible = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeDCxThorcam(app)
            delete(app.MemoryTimer)
            delete(app)
            
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            captureImage(app);
        end

        % Value changed function: heightpxEditField, 
        % widthpxEditField, xminpxEditField, yminpxEditField
        function SizeFieldValueChanged(app, event)
            createBox(app);   
        end

        % Value changed function: LiveButton
        function LiveButtonPushed(app, event)
            if strcmp(app.MemoryTimer.Running, 'off')
                start(app.MemoryTimer);
                app.LiveButton.Value = 1;
            else
                stop(app.MemoryTimer);
                app.LiveButton.Value = 0;
            end
        end

        % Value changed function: PeriodsEditField
        function PeriodsEditFieldValueChanged(app, event)
            value = app.PeriodsEditField.Value;
            stop(app.MemoryTimer);
            app.MemoryTimer.Period=value;
            start(app.MemoryTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Gaussian Beam Fitting';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = 1;
            app.ImageAxes.YTick = 1;
            app.ImageAxes.ZTick = [];
            app.VerticalCutAxes = uiaxes(app.UIFigure);
            title(app.VerticalCutAxes, 'Vertical Cut')
            xlabel(app.VerticalCutAxes, 'size (px)')
            ylabel(app.VerticalCutAxes, 'Intensity')
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Airy', 'Gaussian', 'WEB Camera', 'Thorlabs DCC Camera'};
            app.DropDown.ItemsData = {'airy beam.bmp', 'Gaussian beam.bmp', 'WEBcam', 'Thorlabs DCx cam'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Gaussian beam.bmp';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.HorizontalCutAxes = uiaxes(app.UIFigure);
            title(app.HorizontalCutAxes, {'Horizontal Cut'; ''})
            xlabel(app.HorizontalCutAxes, 'size (px)')
            ylabel(app.HorizontalCutAxes, 'Intensity')
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @fit, true);
            app.FitButton.Text = 'Fit';
            app.ResizeFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ResizeFactorEditFieldLabel.Text = 'Resize Factor';
            app.ResizeFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResizeFactorEditField.Limits = [1 50];
            app.ResizeFactorEditField.RoundFractionalValues = 'on';
            app.ResizeFactorEditField.ValueDisplayFormat = '%.0f';
            app.ResizeFactorEditField.Value = 4;
            app.PixelConvEditFieldLabel = uilabel(app.UIFigure);
            app.PixelConvEditFieldLabel.Text = 'Pixel Conv';
            app.PixelConvEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelConvEditField.Limits = [1 500];
            app.PixelConvEditField.RoundFractionalValues = 'on';
            app.PixelConvEditField.ValueDisplayFormat = '%.0f';
            app.PixelConvEditField.Value = 1;
            app.FixedRotationCheckBox = uicheckbox(app.UIFigure);
            app.FixedRotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedRotationCheckBoxValueChanged, true);
            app.FixedRotationCheckBox.Text = 'Fixed Rotation';
            app.FixedRotationCheckBox.Value = true;
            app.xminpxEditFieldLabel = uilabel(app.UIFigure);
            app.xminpxEditFieldLabel.Text = {'xmin (px)'; ''};
            app.xminpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminpxEditField.Limits = [1 2000];
            app.xminpxEditField.RoundFractionalValues = 'on';
            app.xminpxEditField.ValueChangedFcn = createCallbackFcn(app, @SizeFieldValueChanged, true);
            app.xminpxEditField.Value = 1;
            app.widthpxEditFieldLabel = uilabel(app.UIFigure);
            app.widthpxEditFieldLabel.Text = {'width (px)'; ''};
            app.widthpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthpxEditField.Limits = [1 2000];
            app.widthpxEditField.RoundFractionalValues = 'on';
            app.widthpxEditField.ValueChangedFcn = createCallbackFcn(app, @SizeFieldValueChanged, true);
            app.widthpxEditField.Value = 1280;
            app.heightpxEditFieldLabel = uilabel(app.UIFigure);
            app.heightpxEditFieldLabel.Text = {'height (px)'; ''};
            app.heightpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightpxEditField.Limits = [1 2000];
            app.heightpxEditField.RoundFractionalValues = 'on';
            app.heightpxEditField.ValueChangedFcn = createCallbackFcn(app, @SizeFieldValueChanged, true);
            app.heightpxEditField.Value = 1024;
            app.yminpxEditFieldLabel = uilabel(app.UIFigure);
            app.yminpxEditFieldLabel.Text = {'ymin (px)'; ''};
            app.yminpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminpxEditField.Limits = [1 2000];
            app.yminpxEditField.RoundFractionalValues = 'on';
            app.yminpxEditField.ValueChangedFcn = createCallbackFcn(app, @SizeFieldValueChanged, true);
            app.yminpxEditField.Value = 1;
            app.RotationangleEditFieldLabel = uilabel(app.UIFigure);
            app.RotationangleEditFieldLabel.Text = 'Rotation angle';
            app.RotationangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.Editable = 'off';
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.Editable = 'off';
            app.waistxmmEditFieldLabel = uilabel(app.UIFigure);
            app.waistxmmEditFieldLabel.Text = 'waist x (mm)';
            app.waistxmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.waistxmmEditField.Editable = 'off';
            app.waistymmEditFieldLabel = uilabel(app.UIFigure);
            app.waistymmEditFieldLabel.Text = 'waist y (mm)';
            app.waistymmEditField = uieditfield(app.UIFigure, 'numeric');
            app.waistymmEditField.ValueChangedFcn = createCallbackFcn(app, @fit, true);
            app.waistymmEditField.Editable = 'off';
            app.RotationEditFieldLabel = uilabel(app.UIFigure);
            app.RotationEditFieldLabel.Visible = 'off';
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationEditField.Visible = 'off';
            app.ExpmsEditFieldLabel = uilabel(app.UIFigure);
            app.ExpmsEditFieldLabel.Visible = 'off';
            app.ExpmsEditFieldLabel.Text = 'Exp (ms)';
            app.ExpmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExpmsEditField.Limits = [0.01 2000];
            app.ExpmsEditField.ValueDisplayFormat = '%11.2g';
            app.ExpmsEditField.Visible = 'off';
            app.ExpmsEditField.Value = 0.3;
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Visible = 'off';
            app.CaptureButton.Text = 'Capture';
            app.PixelsizemLabel = uilabel(app.UIFigure);
            app.PixelsizemLabel.Text = 'Pixel size (μm)';
            app.PixelsizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizemEditField.Value = 5.2;
            app.LiveButton = uibutton(app.UIFigure, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonPushed, true);
            app.LiveButton.Visible = 'off';
            app.LiveButton.Text = 'Live';
            app.PeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsEditFieldLabel.Visible = 'off';
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.PeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsEditField.Limits = [0.01 2000];
            app.PeriodsEditField.ValueDisplayFormat = '%11.2g';
            app.PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsEditFieldValueChanged, true);
            app.PeriodsEditField.Visible = 'off';
            app.PeriodsEditField.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamFitting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end